classdef Proyecto_CalculoRenal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        AppTab                     matlab.ui.container.Tab
        VolumenCal                 matlab.ui.control.NumericEditField
        VolumendelClculoLabel      matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        XLabel                     matlab.ui.control.Label
        Coor_Y                     matlab.ui.control.NumericEditField
        SegmentacindelClculoLabel  matlab.ui.control.Label
        readeInteresLabel          matlab.ui.control.Label
        ImagenCompletaCTLabel      matlab.ui.control.Label
        Image2_2                   matlab.ui.control.Image
        V_3DButton                 matlab.ui.control.Button
        Label_6                    matlab.ui.control.Label
        Coor_X                     matlab.ui.control.NumericEditField
        BuscarButton               matlab.ui.control.Button
        CoordenadasdelClculoLabel  matlab.ui.control.Label
        EncontrarClculoRenalLabel  matlab.ui.control.Label
        AbrirButton                matlab.ui.control.Button
        AnalizarNuevaCTLabel       matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        ReconocimientodeclculorenalenimgenesCTLabel  matlab.ui.control.Label
        Segmentacion_2             matlab.ui.control.UIAxes
        Segmentacion               matlab.ui.control.UIAxes
        Zonas                      matlab.ui.control.UIAxes
        Completa                   matlab.ui.control.UIAxes
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = public)
        ROI
        BW3
        nslices
        ncols
        XF
        x
        y
        volumen2
    end
    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pause(1);
            [filename, pathname] = uigetfile('*.nrrd*', 'Elije tu Archivo');
            filename=strcat(pathname,filename);
            [X, meta] = nrrdread(filename);
             H = waitbar(0,'Abriendo…');
                pause(1);
            [n_rows, n_cols, n_slices] = size(X);
            
            %% Aplicacion de Spacing
            sd = meta.spacedirections;
            str = sd;
            [C,matches] = strsplit(str,{'(',',',',',')'});
            s_cols = str2double(C(2));
            s_rows = str2double(C(7));
            s_slices = str2double(C(12));
            largo = round(s_cols*n_cols/10);
            ancho = round(s_rows*n_rows/10);
            alto = round(s_slices*n_slices/10);
            voxel = s_cols*s_rows*s_slices;
            tamMax = 15/voxel;
            tamMin = 4/voxel;

            sep = round(s_cols*n_cols/10);%C
            
            %% Ajuste de Imagen de Fondo
            n_corte = Cortadora(X);
            
            
            Imagen = permute(X(n_corte,:,:),[3 2 1]);
            Imagen3D = imrotate(Imagen,180);
            
            %% Segmentacion Houns1field en Pulmones
            min_tejido = -700;
            max_tejido = -300;
            tejido = X > min_tejido & X < max_tejido;
            slice_tejido= permute(tejido(n_corte,:,:),[3 2 1]);
            slice_tejido = imrotate(slice_tejido,180);
            minimo_Cols = 10;
            minimo_Slices = 10;
            
            BW2 = bwareaopen(slice_tejido,400);
            %Rows -> Horizontal
            %Slices -> Vertical
            imshow(Imagen3D,[-160,240],'Parent',app.Completa);
            
            %% Pixel de Referencia en Y
            PixelRef = 20;
            for i = 1: n_slices
                for j = 1: n_cols
                    if(BW2(i,j,1) == 1)
                        if(minimo_Slices < i)
                        minimo_Slices = i;
                        PixelRef = minimo_Slices;
                        end 
                    end 
                end
            end 
                ROIY = Imagen3D(PixelRef:round(PixelRef+(200/s_slices)),:,1); 
             
                %% Pixel de Referencia en X
            
            ROIX = ROIY > 140 & ROIY < 1500;
            PixelRef2 = 0; 
            bandX = 0;
            acum = 0;
            P = 0;
            [n_slices1, n_cols1] = size(ROIX);
            for i = 1: n_slices1
                for j = 1: n_cols1
                    if(ROIX(i,j,1) == 1)
                        bandX = 1;
                    end 
                    if(ROIX(i,j,1) == 0 && bandX == 1 && acum == 0)
                    PixelRef2 = j;
                    P = i;
                    acum = 1;
                    end
                end
            end 
            
            app.ROI = ROIY(:,PixelRef2:n_cols1-PixelRef2,1);

                
            %% Segmentar Piedra
            ROI_bin = app.ROI > 140 & app.ROI < 1500;
            im = ROI_bin(:,:,1);
            [app.nslices,app.ncols] = size(im);
            i_max = max(max(im)); im = im > 0.5*i_max; i_max = max(max(im));
            i_min = min(min(im));
            [L,n_o] = bwlabel(im,4);
            i_max_label = max(max(L));
            i_min_label = min(min(L));
            
            CC = bwconncomp(im,4);
            n_obj = CC.NumObjects;
                pixels_obj_1 = CC.PixelIdxList{1};
                im_copy = im;
            
            for n=1:n_obj
                [area_obj,x] = size(CC.PixelIdxList{n});
                if(area_obj < 35) 
                    for i = 1:area_obj
                        [r,c] = IndexToCoordinates(CC.PixelIdxList{n}(i),app.nslices);
                        if(r == 0)
                        r = 1;
                        end
                        im_copy(r,c) = 0;
                      
                    end
                end
            end
            
            new_CC = bwconncomp(im_copy,4);
            new_n_obj = new_CC.NumObjects;
            seg = im_copy;
            CC_boundaries = bwboundaries(im_copy,4);
            stats = regionprops(new_CC, 'all');
            
            for n=1:new_n_obj
              
                [area_obj_filtered,x] = size(new_CC.PixelIdxList{n});
                [perimeter,x] = size(CC_boundaries{n});
                compacity_coeficcient = (2*pi*sqrt(area_obj_filtered/pi))/perimeter;
                
                if (compacity_coeficcient < 0.8)
                    for i = 1:area_obj_filtered
                        [r,c] = IndexToCoordinates(new_CC.PixelIdxList{n}(i),app.nslices);
                        if(r == 0)
                        r = 1;
                        end
                        im_copy(r,c) = 0;
                    end
                end
                
            end
            app.BW3 = bwareaopen(im_copy,50);
            
             s1 = regionprops(app.BW3,'Area');
             area = s1.Area;
             area2 = 2*area;
             s = regionprops(app.BW3,'centroid');
             app.x = round(s.Centroid(1)); %primero
             app.y = round(s.Centroid(2)); %segundo

             tmax = 12*100;%C
             tmin = 4*100;%C
            
            %% Recortar Imagen 3D
           tmax = 12*100;%C
            tmin = 4*100;%C
            X = imrotate3(X,180,[1 0 0]);
            XN = permute(X(:,PixelRef2:n_cols1-PixelRef2,PixelRef:round(PixelRef+(200/s_slices))),[3 2 1]);%C
            [n_rowsF, n_colsF, n_slicesF] = size(XN);
            X3Dbin = XN > 140 & XN < 1500;
            X3D = X3Dbin(round(app.x-area/2)+10:round(app.x+area),round(n_colsF/2)+sep:n_colsF,200:350);%C
            app.XF = X3D & ~bwareaopen(X3D,tmax);%C
            app.XF = bwareaopen(app.XF,tmin);%C
            %volumeViewer(XF);%C
            vol = regionprops3(app.XF,"Volume");%C
            app.volumen2 = round(vol.Volume(1));%C
            %Volumen adbdominal = 540
            %Volumen completa = 401

            app.AbrirButton.Enable = 'on';
            app.BuscarButton.Enable = 'on';

            waitbar(1,H,'Imagen Cargada');
            pause(2);
            close(H);


        end

        % Button pushed function: V_3DButton
        function V_3DButtonPushed(app, event)
            volumeViewer(app.XF);%C
            app.VolumenCal.Value = app.volumen2;
        end

        % Button pushed function: AbrirButton
        function AbrirButtonPushed(app, event)
            app.Coor_X.Value = 0;
            app.Coor_Y.Value = 0;
            app.VolumenCal.Value = 0;
            app.BuscarButton.Enable = 'off';
            app.AbrirButton.Enable = 'off';
            app.V_3DButton.Enable = 'off';
            reset(app.Completa);
            reset(app.Zonas);
            reset(app.Segmentacion);
            reset(app.Segmentacion_2);
            delete(app.Completa.Children);
            delete(app.Zonas.Children);
            delete(app.Segmentacion.Children);
            delete(app.Segmentacion_2.Children);
            app.Completa.Visible = 'off';
            app.Zonas.Visible = 'off';
            app.Segmentacion.Visible = 'off';
            app.Segmentacion_2.Visible = 'off';
            
           
            startupFcn(app);
        end

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
            H = waitbar(0,'Buscando…');
                pause(3);
           
            imshow(app.ROI(:,:,1),[-160,200],'Parent',app.Zonas);         
            imshow(app.ROI(:,:,1),[-160,200],'Parent',app.Segmentacion);
            red = zeros(app.nslices,app.ncols,3); 
            red(:,:,1) = 1;
            
            h = imshow(red,'Parent',app.Segmentacion_2);
            set(h,'AlphaData',app.BW3(:,:,1));

            app.Coor_X.Value = double(app.x);
            app.Coor_Y.Value = double(app.y);

            waitbar(1,H,'Cálculo Renal Encontrado');
            pause(2);
            close(H);

            app.V_3DButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AppTab = uitab(app.TabGroup);
            app.AppTab.Title = 'App';
            app.Completa = uiaxes(app.AppTab);
            app.Completa.Visible = 'off';
            app.Zonas = uiaxes(app.AppTab);
            app.Zonas.Visible = 'off';
            app.Segmentacion = uiaxes(app.AppTab);
            app.Segmentacion.Visible = 'off';
            app.Segmentacion_2 = uiaxes(app.AppTab);
            app.Segmentacion_2.Visible = 'off';
            app.ReconocimientodeclculorenalenimgenesCTLabel = uilabel(app.AppTab);
            app.ReconocimientodeclculorenalenimgenesCTLabel.Text = 'Reconocimiento de cálculo renal en imágenes CT';
            app.Label_4 = uilabel(app.AppTab);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.AppTab);
            app.Label_5.Text = '';
            app.AnalizarNuevaCTLabel = uilabel(app.AppTab);
            app.AnalizarNuevaCTLabel.Text = 'Analizar Nueva CT';
            app.AbrirButton = uibutton(app.AppTab, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.AbrirButton.Enable = 'off';
            app.AbrirButton.Text = 'Abrir';
            app.EncontrarClculoRenalLabel = uilabel(app.AppTab);
            app.EncontrarClculoRenalLabel.Text = 'Encontrar Cálculo Renal';
            app.CoordenadasdelClculoLabel = uilabel(app.AppTab);
            app.CoordenadasdelClculoLabel.Text = 'Coordenadas del Cálculo';
            app.BuscarButton = uibutton(app.AppTab, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Enable = 'off';
            app.BuscarButton.Text = 'Buscar';
            app.Coor_X = uieditfield(app.AppTab, 'numeric');
            app.Coor_X.Editable = 'off';
            app.Label_6 = uilabel(app.AppTab);
            app.Label_6.Text = {'Proyecto Final'; ''; 'Integrantes:	'; 'José Manuel Casillas Rodríguez'; 'Luis Armando Silva González'; 'Diego Hernández Ruíz'; 'Oscar Emmanuel Lopez Jiménez'; ''; 'Profesor:    '; 'Dr. Francisco Javier Álvarez Padilla'};
            app.V_3DButton = uibutton(app.AppTab, 'push');
            app.V_3DButton.ButtonPushedFcn = createCallbackFcn(app, @V_3DButtonPushed, true);
            app.V_3DButton.Enable = 'off';
            app.V_3DButton.Text = 'Visualizar Cálculo en 3D';
            app.Image2_2 = uiimage(app.AppTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'udg.png');
            app.ImagenCompletaCTLabel = uilabel(app.AppTab);
            app.ImagenCompletaCTLabel.Text = 'Imagen Completa CT';
            app.readeInteresLabel = uilabel(app.AppTab);
            app.readeInteresLabel.Text = 'Área de Interes';
            app.SegmentacindelClculoLabel = uilabel(app.AppTab);
            app.SegmentacindelClculoLabel.Text = 'Segmentación del Cálculo';
            app.Coor_Y = uieditfield(app.AppTab, 'numeric');
            app.Coor_Y.Editable = 'off';
            app.XLabel = uilabel(app.AppTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.AppTab);
            app.YLabel.Text = 'Y';
            app.VolumendelClculoLabel = uilabel(app.AppTab);
            app.VolumendelClculoLabel.Text = 'Volumen del Cálculo';
            app.VolumenCal = uieditfield(app.AppTab, 'numeric');
            app.VolumenCal.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proyecto_CalculoRenal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end